classdef TensorView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotButtonGroup                 matlab.ui.container.ButtonGroup
        MoleculeandtensorButton         matlab.ui.control.RadioButton
        MoleculeButton                  matlab.ui.control.RadioButton
        TensorButton                    matlab.ui.control.RadioButton
        TensorstyleButtonGroup          matlab.ui.container.ButtonGroup
        OvaloidButton                   matlab.ui.control.RadioButton
        EllipsoidButton                 matlab.ui.control.RadioButton
        Lamp                            matlab.ui.control.Lamp
        TensorcolorLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LoadpdborxyzButton              matlab.ui.control.Button
        TensorvaluemolecularframeLabel  matlab.ui.control.Label
        TensorcoordinatesLabel          matlab.ui.control.Label
        Get3DmodelButton                matlab.ui.control.Button
        TensortransparencySlider        matlab.ui.control.Slider
        s_12EditField                   matlab.ui.control.NumericEditField
        TensortransparencySliderLabel   matlab.ui.control.Label
        s_22EditField                   matlab.ui.control.NumericEditField
        s_12EditFieldLabel              matlab.ui.control.Label
        s_32EditField                   matlab.ui.control.NumericEditField
        s_22EditFieldLabel              matlab.ui.control.Label
        s_11EditField                   matlab.ui.control.NumericEditField
        s_32EditFieldLabel              matlab.ui.control.Label
        s_21EditField                   matlab.ui.control.NumericEditField
        s_11EditFieldLabel              matlab.ui.control.Label
        s_31EditField                   matlab.ui.control.NumericEditField
        s_21EditFieldLabel              matlab.ui.control.Label
        s_13EditField                   matlab.ui.control.NumericEditField
        s_31EditFieldLabel              matlab.ui.control.Label
        s_23EditField                   matlab.ui.control.NumericEditField
        s_13EditFieldLabel              matlab.ui.control.Label
        s_33EditField                   matlab.ui.control.NumericEditField
        s_23EditFieldLabel              matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        s_33EditFieldLabel              matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        zEditFieldLabel                 matlab.ui.control.Label
        TensorscalingEditField          matlab.ui.control.NumericEditField
        RSlider                         matlab.ui.control.Slider
        TensorscalingEditFieldLabel     matlab.ui.control.Label
        GSlider                         matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        BSlider                         matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        MaxbondlengthEditField          matlab.ui.control.NumericEditField
        BSliderLabel                    matlab.ui.control.Label
        MaxbondlengthEditFieldLabel     matlab.ui.control.Label
        AdditionaltensorsCheckBox       matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        EulerAnglesTab                  matlab.ui.container.Tab
        RotationstyleButtonGroup        matlab.ui.container.ButtonGroup
        ActiveZYZButton                 matlab.ui.control.RadioButton
        PassiveZYZButton                matlab.ui.control.RadioButton
        ActiveZXZButton                 matlab.ui.control.RadioButton
        PassiveZXZButton                matlab.ui.control.RadioButton
        MolecularFrameEulerButton_2     matlab.ui.control.Button
        MolecularFrameEulerButton       matlab.ui.control.Button
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        PAS_11EditFieldLabel            matlab.ui.control.Label
        PAS_11EditField                 matlab.ui.control.NumericEditField
        PAS_22EditFieldLabel            matlab.ui.control.Label
        PAS_22EditField                 matlab.ui.control.NumericEditField
        PAS_33EditFieldLabel            matlab.ui.control.Label
        PAS_33EditField                 matlab.ui.control.NumericEditField
        ShieldingShiftButton            matlab.ui.control.Button
        CSArefppmEditField              matlab.ui.control.NumericEditField
        CSArefppmEditFieldLabel         matlab.ui.control.Label
        EuleranglesradLabel             matlab.ui.control.Label
        PrincipalaxistensorLabel        matlab.ui.control.Label
        PAStensororderButtonGroup       matlab.ui.container.ButtonGroup
        AscendingButton                 matlab.ui.control.RadioButton
        DescendingButton                matlab.ui.control.RadioButton
        DipolartensorTab                matlab.ui.container.Tab
        GetdipolartensorButton          matlab.ui.control.Button
        Atom1Label                      matlab.ui.control.Label
        xEditField_2Label               matlab.ui.control.Label
        xEditField_2                    matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        yEditField_2                    matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        zEditField_2                    matlab.ui.control.NumericEditField
        Atom2Label                      matlab.ui.control.Label
        xEditField_3Label               matlab.ui.control.Label
        xEditField_3                    matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        yEditField_3                    matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        zEditField_3                    matlab.ui.control.NumericEditField
        MagneticdipolartensorLabel      matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        ExportwrlButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        fullfilename = ''; % Description
    end
    
    properties (Access = public)
        fig % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s_11EditFieldLabel.Interpreter = 'latex';
            app.s_21EditFieldLabel.Interpreter = 'latex';
            app.s_31EditFieldLabel.Interpreter = 'latex';
            app.s_12EditFieldLabel.Interpreter = 'latex';
            app.s_22EditFieldLabel.Interpreter = 'latex';
            app.s_32EditFieldLabel.Interpreter = 'latex';
            app.s_13EditFieldLabel.Interpreter = 'latex';
            app.s_23EditFieldLabel.Interpreter = 'latex';
            app.s_33EditFieldLabel.Interpreter = 'latex';
            app.s_11EditFieldLabel.Text = '$\sigma_{11}$';
            app.s_21EditFieldLabel.Text = '$\sigma_{21}$';
            app.s_31EditFieldLabel.Text = '$\sigma_{31}$';
            app.s_12EditFieldLabel.Text = '$\sigma_{12}$';
            app.s_22EditFieldLabel.Text = '$\sigma_{22}$';
            app.s_32EditFieldLabel.Text = '$\sigma_{32}$';
            app.s_13EditFieldLabel.Text = '$\sigma_{13}$';
            app.s_23EditFieldLabel.Text = '$\sigma_{23}$';
            app.s_33EditFieldLabel.Text = '$\sigma_{33}$';
            app.Lamp.Color = [app.RSlider.Value,app.GSlider.Value,app.BSlider.Value];
            app.PAS_11EditFieldLabel.Interpreter = 'latex';
            app.PAS_11EditFieldLabel.Text = 'PAS$_{11}$';
            app.PAS_22EditFieldLabel.Interpreter = 'latex';
            app.PAS_22EditFieldLabel.Text = 'PAS$_{22}$';
            app.PAS_33EditFieldLabel.Interpreter = 'latex';
            app.PAS_33EditFieldLabel.Text = 'PAS$_{33}$';
            
            app.AlphaEditFieldLabel.Interpreter = 'latex';
            app.AlphaEditFieldLabel.Text = '$\alpha$';
            app.BetaEditFieldLabel.Interpreter = 'latex';
            app.BetaEditFieldLabel.Text = '$\beta$';
            app.GammaEditFieldLabel.Interpreter = 'latex';
            app.GammaEditFieldLabel.Text = '$\gamma$';
            format short
            app.ExportwrlButton.Enable = 'off';
        end

        % Button pushed function: Get3DmodelButton
        function Get3DmodelButtonPushed(app, event)
            app.Get3DmodelButton.Enable = 'off';
            pause(0.1)
            try
                Fname = app.fullfilename;
                if Fname == ""
                    app.TensorButton.Value = true;
                end
                
                s11 = app.s_11EditField.Value;
                s21 = app.s_21EditField.Value;
                s31 = app.s_31EditField.Value;
                s12 = app.s_12EditField.Value;
                s22 = app.s_22EditField.Value;
                s32 = app.s_32EditField.Value;
                s13 = app.s_13EditField.Value;
                s23 = app.s_23EditField.Value;
                s33 = app.s_33EditField.Value;
                
                x = app.xEditField.Value;
                y = app.yEditField.Value;
                z = app.zEditField.Value;
                CSAref = app.CSArefppmEditField.Value;
                Scale = app.TensorscalingEditField.Value;
                Trans = app.TensortransparencySlider.Value;
                R = app.RSlider.Value;
                G = app.GSlider.Value;
                B = app.BSlider.Value;
                
                Bond = app.MaxbondlengthEditField.Value;
                
                shieldShift = 0;% The app is always in shielding mode when using CreateTensor.m in TensorViewApp.m
                
                if app.OvaloidButton.Value
                    OvEl = "ovaloid";
                end
                if app.EllipsoidButton.Value
                    OvEl = "ellipsoid";
                end
                if app.MoleculeandtensorButton.Value
                    MolTen = 0;
                end
                if app.MoleculeButton.Value
                    MolTen = 1;
                end
                if app.TensorButton.Value
                    MolTen = 2;
                end
                MoreT = app.AdditionaltensorsCheckBox.Value;
                TensorViewApp(Fname,s11,s21,s31,s12,s22,s32,s13,s23,s33,x,y,z,CSAref,Scale,Trans,R,G,B,shieldShift,OvEl,MolTen,Bond,MoreT)
                app.fig = gcf;
                app.Get3DmodelButton.Enable = 'on';
                app.ExportwrlButton.Enable = 'on';
            catch
                app.Get3DmodelButton.Enable = 'on';
            end
        end

        % Button pushed function: LoadpdborxyzButton
        function LoadpdborxyzButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.pdb;*.xyz'});% add ur extension post period within parathesis.
            app.fullfilename = fullfile(pathname,filename);
            app.Label.Text = {filename};
            if contains(app.Label.Text, '.pdb')
                %app.MaxbondlengthEditFieldLabel ;
                %set(app.MaxbondlengthEditField, 'Enable', 'off');
            end % [file,path] = uiputfile
            app.MoleculeandtensorButton.Value = true;
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [changingValue,app.GSlider.Value,app.BSlider.Value];
        end

        % Value changing function: GSlider
        function GSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [app.RSlider.Value,changingValue,app.BSlider.Value];
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Lamp.Color = [app.RSlider.Value,app.GSlider.Value,changingValue];
        end

        % Value changed function: AdditionaltensorsCheckBox
        function AdditionaltensorsCheckBoxValueChanged(app, event)
            value = app.AdditionaltensorsCheckBox.Value;
            if value == true
                app.TensorButton.Value = true;
                set(app.PlotButtonGroup, 'Enable', 'off');
            elseif value == false
                set(app.PlotButtonGroup, 'Enable', 'on');
            end
        end

        % Button pushed function: GetdipolartensorButton
        function GetdipolartensorButtonPushed(app, event)
            V1 = [app.xEditField_2.Value, app.yEditField_2.Value, app.zEditField_2.Value];
            V2 = [app.xEditField_3.Value, app.yEditField_3.Value, app.zEditField_3.Value];
            DT = CreateDipolar(V1,V2);
            
            
            app.s_11EditField.Value = DT(1,1);
            app.s_21EditField.Value = DT(2,1);
            app.s_31EditField.Value = DT(3,1);
            app.s_12EditField.Value = DT(1,2);
            app.s_22EditField.Value = DT(2,2);
            app.s_32EditField.Value = DT(3,2);
            app.s_13EditField.Value = DT(1,3);
            app.s_23EditField.Value = DT(2,3);
            app.s_33EditField.Value = DT(3,3);
            
            app.xEditField.Value = V1(1);
            app.yEditField.Value = V1(2);
            app.zEditField.Value = V1(3);

        end

        % Callback function
        function CSAButtonGroupSelectionChanged(app, event)
            
        end

        % Button pushed function: MolecularFrameEulerButton_2
        function MolecularFrameEulerButton_2Pushed(app, event)
            s11 = app.s_11EditField.Value;
            s21 = app.s_21EditField.Value;
            s31 = app.s_31EditField.Value;
            s12 = app.s_12EditField.Value;
            s22 = app.s_22EditField.Value;
            s32 = app.s_32EditField.Value;
            s13 = app.s_13EditField.Value;
            s23 = app.s_23EditField.Value;
            s33 = app.s_33EditField.Value;
            Tensor = [s11 s12 s13; s21 s22 s23; s31 s32 s33];
            
            if app.ActiveZYZButton.Value
                Mode = "AZYZ";
            end
            if app.PassiveZYZButton.Value
                Mode = "PZYZ";
            end
            if app.ActiveZXZButton.Value
                Mode = "AZXZ";
            end
            if app.PassiveZXZButton.Value
                Mode = "PZXZ";
            end
            
            if app.AscendingButton.Value
                Order = "Ascending";
            end
            if app.DescendingButton.Value
                Order = "Descending";
            end
            %app.MolecularFrameEulerButton_2.Enable = false;
            %try
                [Euler,PAS] = MFtoEuler(Tensor,Mode,Order);
            %catch
                app.MolecularFrameEulerButton_2.Enable = true;
            %end
            app.MolecularFrameEulerButton_2.Enable = true;
            app.AlphaEditField.Value = Euler(1);
            app.BetaEditField.Value = Euler(2);
            app.GammaEditField.Value = Euler(3);
            
            app.PAS_11EditField.Value = PAS(1);
            app.PAS_22EditField.Value = PAS(2);
            app.PAS_33EditField.Value = PAS(3);
            
            
        end

        % Button pushed function: MolecularFrameEulerButton
        function MolecularFrameEulerButtonPushed(app, event)
            Alpha = app.AlphaEditField.Value;
            Beta = app.BetaEditField.Value;
            Gamma = app.GammaEditField.Value;
            PAS = zeros(3,3);
            PAS(1,1) = app.PAS_11EditField.Value;
            PAS(2,2) = app.PAS_22EditField.Value;
            PAS(3,3) = app.PAS_33EditField.Value;
            if app.ActiveZYZButton.Value
                System = "AZYZ";
            end
            if app.PassiveZYZButton.Value
                System = "PZYZ";
            end
            if app.ActiveZXZButton.Value
                System = "AZXZ";
            end
            if app.PassiveZXZButton.Value
                System = "PZXZ";
            end
            
            MF = RotateTensor(Alpha,Beta,Gamma,PAS,System);
            
            ZN = abs(MF)<10^(-15);
            MF(ZN) = 0;
            
            
            app.s_11EditField.Value = MF(1,1);
            app.s_21EditField.Value = MF(2,1);
            app.s_31EditField.Value = MF(3,1);
            app.s_12EditField.Value = MF(1,2);
            app.s_22EditField.Value = MF(2,2);
            app.s_32EditField.Value = MF(3,2);
            app.s_13EditField.Value = MF(1,3);
            app.s_23EditField.Value = MF(2,3);
            app.s_33EditField.Value = MF(3,3);
            
        end

        % Button pushed function: ShieldingShiftButton
        function ShieldingShiftButtonPushed(app, event)
                app.PAS_11EditField.Value = app.CSArefppmEditField.Value - app.PAS_11EditField.Value;
                app.PAS_22EditField.Value = app.CSArefppmEditField.Value - app.PAS_22EditField.Value;
                app.PAS_33EditField.Value = app.CSArefppmEditField.Value - app.PAS_33EditField.Value;
        end

        % Button pushed function: ExportwrlButton
        function ExportwrlButtonPushed(app, event)
            app.ExportwrlButton.Enable = 'off';
            pause(0.1)
            try
            [filename,path] = uiputfile('*.wrl');
            fig = app.fig;
            Exportsurfwrl(fig,filename,path);
            catch
                app.ExportwrlButton.Enable = 'on';
            end
            app.ExportwrlButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlotButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotButtonGroup.Title = 'Plot';
            app.MoleculeandtensorButton = uiradiobutton(app.PlotButtonGroup);
            app.MoleculeandtensorButton.Text = 'Molecule and tensor';
            app.MoleculeandtensorButton.Value = true;
            app.MoleculeButton = uiradiobutton(app.PlotButtonGroup);
            app.MoleculeButton.Text = 'Molecule';
            app.TensorButton = uiradiobutton(app.PlotButtonGroup);
            app.TensorButton.Text = 'Tensor';
            app.TensorstyleButtonGroup = uibuttongroup(app.UIFigure);
            app.TensorstyleButtonGroup.Tooltip = {'Switches between the two tensor visualization versions.'};
            app.TensorstyleButtonGroup.Title = 'Tensor style';
            app.OvaloidButton = uiradiobutton(app.TensorstyleButtonGroup);
            app.OvaloidButton.Text = 'Ovaloid';
            app.OvaloidButton.Value = true;
            app.EllipsoidButton = uiradiobutton(app.TensorstyleButtonGroup);
            app.EllipsoidButton.Text = 'Ellipsoid';
            app.Lamp = uilamp(app.UIFigure);
            app.TensorcolorLabel = uilabel(app.UIFigure);
            app.TensorcolorLabel.Text = 'Tensor color';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.LoadpdborxyzButton = uibutton(app.UIFigure, 'push');
            app.LoadpdborxyzButton.ButtonPushedFcn = createCallbackFcn(app, @LoadpdborxyzButtonPushed, true);
            app.LoadpdborxyzButton.Text = 'Load .pdb or .xyz';
            app.TensorvaluemolecularframeLabel = uilabel(app.UIFigure);
            app.TensorvaluemolecularframeLabel.Text = 'Tensor value (molecular frame)';
            app.TensorcoordinatesLabel = uilabel(app.UIFigure);
            app.TensorcoordinatesLabel.Text = 'Tensor coordinates (Å)';
            app.Get3DmodelButton = uibutton(app.UIFigure, 'push');
            app.Get3DmodelButton.ButtonPushedFcn = createCallbackFcn(app, @Get3DmodelButtonPushed, true);
            app.Get3DmodelButton.Tooltip = {'The figure can take a few seconds to load.'; ''; 'If matlab is installed, you can find aditional camera options in the tool bar.'};
            app.Get3DmodelButton.Text = 'Get 3D model';
            app.TensortransparencySlider = uislider(app.UIFigure);
            app.TensortransparencySlider.Limits = [0.2 1];
            app.TensortransparencySlider.MajorTicks = [0.2 0.36 0.52 0.68 0.84 1];
            app.TensortransparencySlider.MajorTickLabels = {'0.2', '0.4', '0.5', '0.6', '0.8', '1'};
            app.TensortransparencySlider.Value = 0.5;
            app.s_12EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_12EditField.ValueDisplayFormat = '%.2f';
            app.s_12EditField.Value = -60.302;
            app.TensortransparencySliderLabel = uilabel(app.UIFigure);
            app.TensortransparencySliderLabel.Text = {'Tensor '; 'transparency'};
            app.s_22EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_22EditField.ValueDisplayFormat = '%.2f';
            app.s_22EditField.Value = -23.0881;
            app.s_12EditFieldLabel = uilabel(app.UIFigure);
            app.s_12EditFieldLabel.Text = 's_12';
            app.s_32EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_32EditField.ValueDisplayFormat = '%.2f';
            app.s_32EditField.Value = -28.2399;
            app.s_22EditFieldLabel = uilabel(app.UIFigure);
            app.s_22EditFieldLabel.Text = 's_22';
            app.s_11EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_11EditField.ValueDisplayFormat = '%.2f';
            app.s_11EditField.Value = -5.9766;
            app.s_32EditFieldLabel = uilabel(app.UIFigure);
            app.s_32EditFieldLabel.Text = 's_32';
            app.s_21EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_21EditField.ValueDisplayFormat = '%.2f';
            app.s_21EditField.Value = -65.5206;
            app.s_11EditFieldLabel = uilabel(app.UIFigure);
            app.s_11EditFieldLabel.Text = 's_11';
            app.s_31EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_31EditField.ValueDisplayFormat = '%.2f';
            app.s_31EditField.Value = -9.5073;
            app.s_21EditFieldLabel = uilabel(app.UIFigure);
            app.s_21EditFieldLabel.Text = 's_21';
            app.s_13EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_13EditField.ValueDisplayFormat = '%.2f';
            app.s_13EditField.Value = -10.8928;
            app.s_31EditFieldLabel = uilabel(app.UIFigure);
            app.s_31EditFieldLabel.Text = 's_31';
            app.s_23EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_23EditField.ValueDisplayFormat = '%.2f';
            app.s_23EditField.Value = -25.2372;
            app.s_13EditFieldLabel = uilabel(app.UIFigure);
            app.s_13EditFieldLabel.Text = 's_13';
            app.s_33EditField = uieditfield(app.UIFigure, 'numeric');
            app.s_33EditField.ValueDisplayFormat = '%.2f';
            app.s_33EditField.Value = 56.2779;
            app.s_23EditFieldLabel = uilabel(app.UIFigure);
            app.s_23EditFieldLabel.Text = 's_23';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditField.ValueDisplayFormat = '%.3f';
            app.xEditField.Value = -4.299848;
            app.s_33EditFieldLabel = uilabel(app.UIFigure);
            app.s_33EditFieldLabel.Text = 's_33';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField.ValueDisplayFormat = '%.3f';
            app.yEditField.Value = -0.400088;
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditField.ValueDisplayFormat = '%.3f';
            app.zEditField.Value = -0.033384;
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.TensorscalingEditField = uieditfield(app.UIFigure, 'numeric');
            app.TensorscalingEditField.Tooltip = {'Makes the tensor larger or smaller.'};
            app.TensorscalingEditField.Value = 1;
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [0 1];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.Value = 1;
            app.TensorscalingEditFieldLabel = uilabel(app.UIFigure);
            app.TensorscalingEditFieldLabel.Text = 'Tensor scaling';
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [0 1];
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @GSliderValueChanging, true);
            app.GSlider.Value = 0.5;
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0 1];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.MaxbondlengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxbondlengthEditField.Tooltip = {'Only used for .xyz, '; '.pdb uses connection lists'};
            app.MaxbondlengthEditField.Value = 1.6;
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.MaxbondlengthEditFieldLabel = uilabel(app.UIFigure);
            app.MaxbondlengthEditFieldLabel.Tooltip = {'Max bond length is used for: ".xyz" and ".pdb" without a connection list. '};
            app.MaxbondlengthEditFieldLabel.Text = 'Max bond length (Å)';
            app.AdditionaltensorsCheckBox = uicheckbox(app.UIFigure);
            app.AdditionaltensorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdditionaltensorsCheckBoxValueChanged, true);
            app.AdditionaltensorsCheckBox.Tooltip = {'Adds additional tensors when Get 3D model is pressed.'};
            app.AdditionaltensorsCheckBox.Text = 'Additional tensors';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EulerAnglesTab = uitab(app.TabGroup);
            app.EulerAnglesTab.Title = 'Euler Angles';
            app.RotationstyleButtonGroup = uibuttongroup(app.EulerAnglesTab);
            app.RotationstyleButtonGroup.Tooltip = {'Active rotation solutions: {a,b,g+Pi}, {a+Pi,Pi-b,Pi-g}, and {a+Pi,Pi-b,2Pi-g}'; 'Passive rotation solutions: {a+Pi,b,g }, {Pi-a,Pi-b,Pi+g}, and {2Pi-a,Pi-b,Pi+g}'};
            app.RotationstyleButtonGroup.Title = 'Rotation style';
            app.ActiveZYZButton = uiradiobutton(app.RotationstyleButtonGroup);
            app.ActiveZYZButton.Text = 'Active Z-Y-Z';
            app.ActiveZYZButton.Value = true;
            app.PassiveZYZButton = uiradiobutton(app.RotationstyleButtonGroup);
            app.PassiveZYZButton.Text = 'Passive Z-Y-Z';
            app.ActiveZXZButton = uiradiobutton(app.RotationstyleButtonGroup);
            app.ActiveZXZButton.Text = 'Active Z-X-Z';
            app.PassiveZXZButton = uiradiobutton(app.RotationstyleButtonGroup);
            app.PassiveZXZButton.Text = 'Passive Z-X-Z';
            app.MolecularFrameEulerButton_2 = uibutton(app.EulerAnglesTab, 'push');
            app.MolecularFrameEulerButton_2.ButtonPushedFcn = createCallbackFcn(app, @MolecularFrameEulerButton_2Pushed, true);
            app.MolecularFrameEulerButton_2.Tooltip = {'Translates molecular frame tensor to PAS tensor and Euler angles.'; ''; '*Symmetric tensors can produce multiple correct solutions due to loss of degrees of freedom.'};
            app.MolecularFrameEulerButton_2.Text = ' Molecular Frame -->  Euler';
            app.MolecularFrameEulerButton = uibutton(app.EulerAnglesTab, 'push');
            app.MolecularFrameEulerButton.ButtonPushedFcn = createCallbackFcn(app, @MolecularFrameEulerButtonPushed, true);
            app.MolecularFrameEulerButton.Text = 'Molecular Frame <-- Euler';
            app.AlphaEditFieldLabel = uilabel(app.EulerAnglesTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.AlphaEditField.ValueDisplayFormat = '%.2f';
            app.BetaEditFieldLabel = uilabel(app.EulerAnglesTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.BetaEditField.ValueDisplayFormat = '%.2f';
            app.GammaEditFieldLabel = uilabel(app.EulerAnglesTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.GammaEditField.ValueDisplayFormat = '%.2f';
            app.PAS_11EditFieldLabel = uilabel(app.EulerAnglesTab);
            app.PAS_11EditFieldLabel.Text = 'PAS_11';
            app.PAS_11EditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.PAS_11EditField.ValueDisplayFormat = '%.2f';
            app.PAS_22EditFieldLabel = uilabel(app.EulerAnglesTab);
            app.PAS_22EditFieldLabel.Text = 'PAS_22';
            app.PAS_22EditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.PAS_22EditField.ValueDisplayFormat = '%.2f';
            app.PAS_33EditFieldLabel = uilabel(app.EulerAnglesTab);
            app.PAS_33EditFieldLabel.Text = 'PAS_33';
            app.PAS_33EditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.PAS_33EditField.ValueDisplayFormat = '%.2f';
            app.ShieldingShiftButton = uibutton(app.EulerAnglesTab, 'push');
            app.ShieldingShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShieldingShiftButtonPushed, true);
            app.ShieldingShiftButton.Tooltip = {'Shift = CSA ref - Shielding'; ''; 'Switches between chemical shielding/shift anisotropy (NMR spectroscopy)'};
            app.ShieldingShiftButton.Text = 'Shielding <---> Shift';
            app.CSArefppmEditField = uieditfield(app.EulerAnglesTab, 'numeric');
            app.CSArefppmEditField.ValueDisplayFormat = '%.2f';
            app.CSArefppmEditFieldLabel = uilabel(app.EulerAnglesTab);
            app.CSArefppmEditFieldLabel.Text = 'CSA ref (ppm)';
            app.EuleranglesradLabel = uilabel(app.EulerAnglesTab);
            app.EuleranglesradLabel.Text = 'Euler angles (rad)';
            app.PrincipalaxistensorLabel = uilabel(app.EulerAnglesTab);
            app.PrincipalaxistensorLabel.Text = 'Principal axis tensor';
            app.PAStensororderButtonGroup = uibuttongroup(app.EulerAnglesTab);
            app.PAStensororderButtonGroup.Title = 'PAS tensor order';
            app.AscendingButton = uiradiobutton(app.PAStensororderButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.AscendingButton.Value = true;
            app.DescendingButton = uiradiobutton(app.PAStensororderButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DipolartensorTab = uitab(app.TabGroup);
            app.DipolartensorTab.Title = 'Dipolar tensor';
            app.GetdipolartensorButton = uibutton(app.DipolartensorTab, 'push');
            app.GetdipolartensorButton.ButtonPushedFcn = createCallbackFcn(app, @GetdipolartensorButtonPushed, true);
            app.GetdipolartensorButton.Text = 'Get dipolar tensor';
            app.Atom1Label = uilabel(app.DipolartensorTab);
            app.Atom1Label.Text = 'Atom 1';
            app.xEditField_2Label = uilabel(app.DipolartensorTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.xEditField_2.ValueDisplayFormat = '%.2f';
            app.xEditField_2.Value = -1.921142;
            app.yEditField_2Label = uilabel(app.DipolartensorTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.yEditField_2.ValueDisplayFormat = '%.2f';
            app.yEditField_2.Value = 0.003155;
            app.zEditField_2Label = uilabel(app.DipolartensorTab);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.DipolartensorTab, 'numeric');
            app.zEditField_2.ValueDisplayFormat = '%.2f';
            app.zEditField_2.Value = -0.159581;
            app.Atom2Label = uilabel(app.DipolartensorTab);
            app.Atom2Label.Text = 'Atom 2';
            app.xEditField_3Label = uilabel(app.DipolartensorTab);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.xEditField_3.ValueDisplayFormat = '%.2f';
            app.xEditField_3.Value = -2.203126;
            app.yEditField_3Label = uilabel(app.DipolartensorTab);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.yEditField_3.ValueDisplayFormat = '%.2f';
            app.yEditField_3.Value = -0.92452;
            app.zEditField_3Label = uilabel(app.DipolartensorTab);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.DipolartensorTab, 'numeric');
            app.zEditField_3.ValueDisplayFormat = '%.2f';
            app.zEditField_3.Value = -0.543723;
            app.MagneticdipolartensorLabel = uilabel(app.DipolartensorTab);
            app.MagneticdipolartensorLabel.Text = 'Magnetic dipolar tensor';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'   TensorView for Matlab is a tool to visualize chemical shift/shielding tensors in a molecular context. TensorView for Matlab can read arbitrary .pdb and .xyz files for molecular visualization. Any 3D tensor can be used for visualisation though the chemical shift/shielding tensor is used as an input in script.'; '   '; '   TensorView for Matlab is licenced by creative commons CC BY. https://creativecommons.org/licenses/'; 'Free to share and adapt. Give appropriate credits to authors:'; '------ Credits'; 'github.com/LeoSvenningsson/TensorViewforMatlab'; ''; 'mathworks.com/matlabcentral/fileexchange/55231-molecoule3d'; ''; 'onlinelibrary.wiley.com/doi/full/10.1002/mrc.4793'; ''; '   Version: 1.14'; ''; 'Authors: '; 'Dr. Leo Svenningsson (leo.svenningsson@fkem1.lu.se) '; 'Dr. André Ludwig (aludwig@alumni.ethz.ch)'; 'Prof. Leonard Mueller (leonard.mueller@ucr.edu)'; '------ '; '   TensorView for Matlab is a collaboration with works derrived from molecule3D.m (André Ludwig: mathworks.com/matlabcentral/fileexchange/55231-molecule3d) and TensorView (Prof. Leonard Mueller: https://onlinelibrary.wiley.com/doi/full/10.1002/mrc.4793 and https://sites.google.com/ucr.edu/Mueller/home/tensorview)'};
            app.ExportwrlButton = uibutton(app.UIFigure, 'push');
            app.ExportwrlButton.ButtonPushedFcn = createCallbackFcn(app, @ExportwrlButtonPushed, true);
            app.ExportwrlButton.Text = 'Export .wrl';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TensorView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end